classdef classifyRGCs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        EditMenu            matlab.ui.container.Menu
        maingrid            matlab.ui.container.GridLayout
        Tpanel              matlab.ui.container.GridLayout
        T                   matlab.ui.control.Table
        buttonspanel        matlab.ui.container.GridLayout
        OffparasolButton    matlab.ui.control.Button
        OnparasolButton     matlab.ui.control.Button
        OffmidgetButton     matlab.ui.control.Button
        OnmidgetButton      matlab.ui.control.Button
        OffsmoothButton     matlab.ui.control.Button
        OnsmoothButton      matlab.ui.control.Button
        DSButton            matlab.ui.control.Button
        OSButton            matlab.ui.control.Button
        BistratifiedButton  matlab.ui.control.Button
        UnknownButton       matlab.ui.control.Button
        BigspikesButton     matlab.ui.control.Button
        CommentButton       matlab.ui.control.Button
        LoadDataButton      matlab.ui.control.Button
        themeSwitch         matlab.ui.control.Switch
        refereshplots       matlab.ui.control.Button
        savealldata         matlab.ui.control.Button
        classifyprogress    matlab.ui.control.Gauge
        datapanel           matlab.ui.container.GridLayout
        datapanel2          matlab.ui.container.GridLayout
        pcapoppanel         matlab.ui.container.GridLayout
        poppanel            matlab.ui.container.GridLayout
        OffparasolLabel     matlab.ui.control.Label
        OnparasolLabel      matlab.ui.control.Label
        OffmidgetLabel      matlab.ui.control.Label
        OnmidgetLabel       matlab.ui.control.Label
        acgpop1             matlab.ui.control.UIAxes
        acgpop2             matlab.ui.control.UIAxes
        acgpop3             matlab.ui.control.UIAxes
        acgpop4             matlab.ui.control.UIAxes
        rfpop1              matlab.ui.control.UIAxes
        rfpop2              matlab.ui.control.UIAxes
        rfpop3              matlab.ui.control.UIAxes
        rfpop4              matlab.ui.control.UIAxes
        tcpop1              matlab.ui.control.UIAxes
        tcpop2              matlab.ui.control.UIAxes
        tcpop3              matlab.ui.control.UIAxes
        tcpop4              matlab.ui.control.UIAxes
        nlpop1              matlab.ui.control.UIAxes
        nlpop2              matlab.ui.control.UIAxes
        nlpop3              matlab.ui.control.UIAxes
        nlpop4              matlab.ui.control.UIAxes
        pcagrid             matlab.ui.container.GridLayout
        pca1                matlab.ui.control.UIAxes
        pca2                matlab.ui.control.UIAxes
        pca3                matlab.ui.control.UIAxes
        singlecellpanel     matlab.ui.container.GridLayout
        acg                 matlab.ui.control.UIAxes
        tempcomp            matlab.ui.control.UIAxes
        RFall               matlab.ui.control.UIAxes
        spatialcomp         matlab.ui.control.UIAxes
        sta                 matlab.ui.control.UIAxes
        dspanel             matlab.ui.container.GridLayout
        ds1                 matlab.ui.control.UIAxes
        ds2                 matlab.ui.control.UIAxes
        ds3                 matlab.ui.control.UIAxes
        ds4                 matlab.ui.control.UIAxes
        ds5                 matlab.ui.control.UIAxes
        ds6                 matlab.ui.control.UIAxes
        celltitle           matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function startupplotter(app)
            helper.startupplotApp(app);
        end
        
        function updateplots(app)
            helper.updateplotsApp(app);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.datapanel.Visible = 'off';
            helper.colorThemeApp(app, 'light');
            app.T.UserData.previdx = 1;
            app.T.UserData.curridx = 1;
        end

        % Cell selection callback: T
        function TCellSelection(app, event)
            app.T.UserData.previdx = app.T.UserData.curridx;
            indices = event.Indices;
            app.T.UserData.curridx = indices(1,1);
            removeStyle(app.T);
            addStyle(app.T, app.T.UserData.tablestyle,'row',indices(1,1));
            updateplots(app);
        end

        % Key press function: UIFigure
        function classifyRGCsKeyPress(app, event)
            key = event.Key;
            switch key
                case 'downarrow'
                    app.T.UserData.previdx = app.T.UserData.curridx;
                    indices = app.T.UserData.curridx+1;
                    app.T.UserData.curridx = indices(1,1);
                    removeStyle(app.T);
                    addStyle(app.T, app.T.UserData.tablestyle,'row',indices(1,1));
                    updateplots(app);
                    
                case 'uparrow'
                    app.T.UserData.previdx = app.T.UserData.curridx;
                    indices = app.T.UserData.curridx - 1;
                    app.T.UserData.curridx = indices(1,1);
                    removeStyle(app.T);
                    addStyle(app.T, app.T.UserData.tablestyle,'row',indices(1,1));
                    updateplots(app);
                    
                case '1'
                    OffparasolButtonPushed(app, event);
                case '2'
                    OnparasolButtonPushed(app, event);
                case '3'
                    OffmidgetButtonPushed(app,event);
                case '4'
                    OnmidgetButtonPushed(app,event);
                case '5'
                    OffsmoothButtonPushed(app,event);
                case '6'
                    OnsmoothButtonPushed(app,event);
                case '7'
                    DSButtonPushed(app,event);
                case '8'
                    OSButtonPushed(app,event);
                case '9'
                    BistratifiedButtonPushed(app,event);
                case 'b'
                    BigspikesButtonPushed(app,event);
                case 'u'
                    UnknownButtonPushed(app,event);
                case 'l'
                    CommentButtonPushed(app, event);
                case 'n'
                    app.T.Data{app.T.UserData.curridx,6} = 'noise';
                case 'c'
                    app.T.Data{app.T.UserData.curridx,6} = '';
                    refereshplotsButtonPushed(app, event);
                    
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %dp = 'D:\2-MARMOSET\20180710_252MEA_MHK\fr_fp_cp\Data Analysis\Manual Classification of Primate Ganglion Cells';
            %dp = uigetdir(['D:',filesep,'2-MARMOSET',filesep],'Select Data Folder');
            dp = 'D:\2-MARMOSET\20180710_60MEA_YE\fr_fp_p42\Data Analysis\Manual Classification of Primate Ganglion Cells';
            app.UIFigure.UserData.savingpath = dp;
            cldata = dir([dp,filesep,'Data for manual classification of cells*']);
            app.UIFigure.UserData.datafilename = [cldata.folder,filesep,cldata.name];
            app.singlecellpanel.UserData = load([cldata.folder,filesep,cldata.name]);
            usercldata = dir([dp,filesep,'Classified retinal ganglion cells for experiment on*']);
            if not(isempty(usercldata))
                usercldata = load([usercldata.folder,filesep,usercldata.name]);
                %[~,sortbyfirstcolumn] = sort([usercldata.Data{:,usercldata.sortedcolumn}],'ascend');
                %app.T.Data = usercldata.Data(sortbyfirstcolumn,:);
                %helper.sortTableDataApp(app, usercldata.sortedcolumn);
                app.T.Data = usercldata.Data;
                app.T.UserData.curridx = usercldata.lastindex;
                app.T.UserData.previdx = usercldata.onetolastindex;
                refereshplotsButtonPushed(app, event);
            else
                clus = num2cell(app.singlecellpanel.UserData.tablevalues);
                %clus(:,5) = round(clus(:,5),1);
                %clus(:,1:4) = cellfun(@(x) sprintf('%d', x),clus(:,1:4), 'UniformOutput',0);
                %clus(:,5) = cellfun(@(x) sprintf('%0.2f', x),clus(:,5), 'UniformOutput',0);
                %clus= strcat(sprintf('<html><tr align=center><td width=%d>', size(clus,1)), clus); % little html trick to align the tex in the table
                app.T.Data = [clus, repmat({''},size(clus,1),2)];  % 2 extra: label, comments
                %app.T.UserData.sortindex = 1:size(app.T.Data,1);
                app.T.UserData.sortedcolumn = 1;
            end
            removeStyle(app.T);
            addStyle(app.T, app.T.UserData.tablestyle,'row',app.T.UserData.curridx);
            startupplotter(app);
            
        end

        % Button pushed function: OffparasolButton
        function OffparasolButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'off parasol';
            helper.plotACGpopApp(app, 1);
            helper.plotRFpopApp(app, 1);
            helper.plotTempComppopApp(app, 1);
            helper.plotNLpopApp(app, 1);
        end

        % Button pushed function: OnparasolButton
        function OnparasolButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'on parasol';
            helper.plotACGpopApp(app, 2);
            helper.plotRFpopApp(app, 2);
            helper.plotTempComppopApp(app, 2);
            helper.plotNLpopApp(app, 2);
        end

        % Button pushed function: OffmidgetButton
        function OffmidgetButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'off midget';
            helper.plotACGpopApp(app, 3);
            helper.plotRFpopApp(app, 3);
            helper.plotTempComppopApp(app, 3);
            helper.plotNLpopApp(app, 3);
        end

        % Button pushed function: OnmidgetButton
        function OnmidgetButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'on midget';
            helper.plotACGpopApp(app, 4);
            helper.plotRFpopApp(app, 4);
            helper.plotTempComppopApp(app, 4);
            helper.plotNLpopApp(app, 4);
        end

        % Button pushed function: OffsmoothButton
        function OffsmoothButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'off smooth';
        end

        % Button pushed function: OnsmoothButton
        function OnsmoothButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'on smooth';
        end

        % Button pushed function: DSButton
        function DSButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'direction selective';
        end

        % Button pushed function: OSButton
        function OSButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'orientation selective';
        end

        % Button pushed function: BistratifiedButton
        function BistratifiedButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'bistratified';
        end

        % Button pushed function: UnknownButton
        function UnknownButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'unknown';
        end

        % Button pushed function: BigspikesButton
        function BigspikesButtonPushed(app, event)
            app.T.Data{app.T.UserData.curridx,6} = 'big spikes';
        end

        % Button pushed function: CommentButton
        function CommentButtonPushed(app, event)
            commentanswer = inputdlg('','comment',[1 60]);
            app.T.Data{app.T.UserData.curridx,7} = commentanswer{1};
        end

        % Value changed function: themeSwitch
        function themeSwitchValueChanged(app, event)
            value = app.themeSwitch.Value;
            switch lower(value)
                case 'light'
                    helper.colorThemeApp(app, 'light');
                case 'dark'
                    helper.colorThemeApp(app, 'dark');
            end
        end

        % Button pushed function: refereshplots
        function refereshplotsButtonPushed(app, event)
            for ii = 1:4
                helper.plotACGpopApp(app, ii);
                helper.plotRFpopApp(app, ii);
                helper.plotTempComppopApp(app, ii);
                helper.plotNLpopApp(app, ii);
            end
        end

        % Button pushed function: savealldata
        function savealldataButtonPushed(app, event)
            [~,sortbyfirstcolumn] = sort([app.T.Data{:,1}],'ascend');
            classifiedRGCs.Data = app.T.Data(sortbyfirstcolumn,:);
            classifiedRGCs.lastindex = app.T.UserData.curridx;
            classifiedRGCs.onetolastindex = app.T.UserData.previdx;
            classifiedRGCs.sortedcolumn = app.T.UserData.sortedcolumn;
            saveingfilename = ['Classified retinal ganglion cells for experiment on ',datemaker(app.UIFigure.UserData.savingpath),'.mat'];
            save([app.UIFigure.UserData.savingpath,filesep,saveingfilename],'-v7.3','-struct','classifiedRGCs');
        end

        % Display data changed function: T
        function TDisplayDataChanged(app, event)
            switch lower(event.Interaction)
                case 'sort'
                    helper.sortTableDataApp(app, event.InteractionColumn);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @classifyRGCsKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.maingrid = uigridlayout(app.UIFigure);
            app.Tpanel = uigridlayout(app.maingrid);
            app.T = uitable(app.Tpanel);
            app.T.ColumnName = {'ch'; 'clus'; 'rating'; 'id'; 'RF diameter'; 'label'; 'comment'};
            app.T.RowName = {};
            app.T.ColumnSortable = true;
            app.T.ColumnEditable = [false false false false false true true];
            app.T.CellSelectionCallback = createCallbackFcn(app, @TCellSelection, true);
            app.T.DisplayDataChangedFcn = createCallbackFcn(app, @TDisplayDataChanged, true);
            app.buttonspanel = uigridlayout(app.Tpanel);
            app.OffparasolButton = uibutton(app.buttonspanel, 'push');
            app.OffparasolButton.ButtonPushedFcn = createCallbackFcn(app, @OffparasolButtonPushed, true);
            app.OffparasolButton.Text = 'Off parasol';
            app.OnparasolButton = uibutton(app.buttonspanel, 'push');
            app.OnparasolButton.ButtonPushedFcn = createCallbackFcn(app, @OnparasolButtonPushed, true);
            app.OnparasolButton.Text = 'On parasol';
            app.OffmidgetButton = uibutton(app.buttonspanel, 'push');
            app.OffmidgetButton.ButtonPushedFcn = createCallbackFcn(app, @OffmidgetButtonPushed, true);
            app.OffmidgetButton.Text = 'Off midget';
            app.OnmidgetButton = uibutton(app.buttonspanel, 'push');
            app.OnmidgetButton.ButtonPushedFcn = createCallbackFcn(app, @OnmidgetButtonPushed, true);
            app.OnmidgetButton.Text = 'On midget';
            app.OffsmoothButton = uibutton(app.buttonspanel, 'push');
            app.OffsmoothButton.ButtonPushedFcn = createCallbackFcn(app, @OffsmoothButtonPushed, true);
            app.OffsmoothButton.Text = 'Off smooth';
            app.OnsmoothButton = uibutton(app.buttonspanel, 'push');
            app.OnsmoothButton.ButtonPushedFcn = createCallbackFcn(app, @OnsmoothButtonPushed, true);
            app.OnsmoothButton.Text = 'On smooth';
            app.DSButton = uibutton(app.buttonspanel, 'push');
            app.DSButton.ButtonPushedFcn = createCallbackFcn(app, @DSButtonPushed, true);
            app.DSButton.Text = 'DS';
            app.OSButton = uibutton(app.buttonspanel, 'push');
            app.OSButton.ButtonPushedFcn = createCallbackFcn(app, @OSButtonPushed, true);
            app.OSButton.Text = 'OS';
            app.BistratifiedButton = uibutton(app.buttonspanel, 'push');
            app.BistratifiedButton.ButtonPushedFcn = createCallbackFcn(app, @BistratifiedButtonPushed, true);
            app.BistratifiedButton.Text = 'Bistratified';
            app.UnknownButton = uibutton(app.buttonspanel, 'push');
            app.UnknownButton.ButtonPushedFcn = createCallbackFcn(app, @UnknownButtonPushed, true);
            app.UnknownButton.Text = 'Unknown';
            app.BigspikesButton = uibutton(app.buttonspanel, 'push');
            app.BigspikesButton.ButtonPushedFcn = createCallbackFcn(app, @BigspikesButtonPushed, true);
            app.BigspikesButton.Text = 'Big spikes';
            app.CommentButton = uibutton(app.buttonspanel, 'push');
            app.CommentButton.ButtonPushedFcn = createCallbackFcn(app, @CommentButtonPushed, true);
            app.CommentButton.Text = 'Comment';
            app.LoadDataButton = uibutton(app.buttonspanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Icon = 'opened-folder-96.png';
            app.LoadDataButton.Text = 'Load Data';
            app.themeSwitch = uiswitch(app.buttonspanel, 'slider');
            app.themeSwitch.Items = {'Light', 'Dark'};
            app.themeSwitch.ValueChangedFcn = createCallbackFcn(app, @themeSwitchValueChanged, true);
            app.themeSwitch.Value = 'Light';
            app.refereshplots = uibutton(app.buttonspanel, 'push');
            app.refereshplots.ButtonPushedFcn = createCallbackFcn(app, @refereshplotsButtonPushed, true);
            app.refereshplots.Icon = 'refresh-100.png';
            app.refereshplots.Text = 'Refresh';
            app.savealldata = uibutton(app.buttonspanel, 'push');
            app.savealldata.ButtonPushedFcn = createCallbackFcn(app, @savealldataButtonPushed, true);
            app.savealldata.Icon = 'icons8-save4-100.png';
            app.savealldata.Text = 'Save';
            app.classifyprogress = uigauge(app.buttonspanel, 'circular');
            app.classifyprogress.Limits = [0 400];
            app.datapanel = uigridlayout(app.maingrid);
            app.datapanel2 = uigridlayout(app.datapanel);
            app.pcapoppanel = uigridlayout(app.datapanel2);
            app.poppanel = uigridlayout(app.pcapoppanel);
            app.OffparasolLabel = uilabel(app.poppanel);
            app.OffparasolLabel.Enable = 'off';
            app.OffparasolLabel.Text = 'Off parasol';
            app.OnparasolLabel = uilabel(app.poppanel);
            app.OnparasolLabel.Enable = 'off';
            app.OnparasolLabel.Text = 'On parasol';
            app.OffmidgetLabel = uilabel(app.poppanel);
            app.OffmidgetLabel.Enable = 'off';
            app.OffmidgetLabel.Text = 'Off midget';
            app.OnmidgetLabel = uilabel(app.poppanel);
            app.OnmidgetLabel.Enable = 'off';
            app.OnmidgetLabel.Text = 'On midget ';
            app.acgpop1 = uiaxes(app.poppanel);
            title(app.acgpop1, '')
            xlabel(app.acgpop1, '')
            ylabel(app.acgpop1, '')
            app.acgpop1.XLim = [0 50];
            app.acgpop1.XTick = [0 25 50];
            app.acgpop2 = uiaxes(app.poppanel);
            title(app.acgpop2, '')
            xlabel(app.acgpop2, '')
            ylabel(app.acgpop2, '')
            app.acgpop2.XLim = [0 50];
            app.acgpop2.XTick = [0 25 50];
            app.acgpop3 = uiaxes(app.poppanel);
            title(app.acgpop3, '')
            xlabel(app.acgpop3, '')
            ylabel(app.acgpop3, '')
            app.acgpop3.XLim = [0 50];
            app.acgpop3.XTick = [0 25 50];
            app.acgpop4 = uiaxes(app.poppanel);
            title(app.acgpop4, '')
            xlabel(app.acgpop4, '')
            ylabel(app.acgpop4, '')
            app.acgpop4.XLim = [0 50];
            app.acgpop4.XTick = [0 25 50];
            app.rfpop1 = uiaxes(app.poppanel);
            title(app.rfpop1, '')
            xlabel(app.rfpop1, '')
            ylabel(app.rfpop1, '')
            app.rfpop1.XTick = [0 800];
            app.rfpop1.XTickLabel = '';
            app.rfpop1.YTick = [0 600];
            app.rfpop1.YTickLabel = '';
            app.rfpop1.ZTick = [];
            app.rfpop2 = uiaxes(app.poppanel);
            title(app.rfpop2, '')
            xlabel(app.rfpop2, '')
            ylabel(app.rfpop2, '')
            app.rfpop2.XTick = [0 800];
            app.rfpop2.XTickLabel = '';
            app.rfpop2.YTick = [0 600];
            app.rfpop2.YTickLabel = '';
            app.rfpop2.ZTick = [];
            app.rfpop3 = uiaxes(app.poppanel);
            title(app.rfpop3, '')
            xlabel(app.rfpop3, '')
            ylabel(app.rfpop3, '')
            app.rfpop3.XTick = [0 800];
            app.rfpop3.XTickLabel = '';
            app.rfpop3.YTick = [0 600];
            app.rfpop3.YTickLabel = '';
            app.rfpop3.ZTick = [];
            app.rfpop4 = uiaxes(app.poppanel);
            title(app.rfpop4, '')
            xlabel(app.rfpop4, '')
            ylabel(app.rfpop4, '')
            app.rfpop4.XTick = [0 800];
            app.rfpop4.XTickLabel = '';
            app.rfpop4.YTick = [0 600];
            app.rfpop4.YTickLabel = '';
            app.rfpop4.ZTick = [];
            app.tcpop1 = uiaxes(app.poppanel);
            title(app.tcpop1, '')
            xlabel(app.tcpop1, '')
            ylabel(app.tcpop1, '')
            app.tcpop1.XLim = [0 500];
            app.tcpop1.YLim = [-0.8 0.8];
            app.tcpop1.XTick = [0 250 500 750 1000];
            app.tcpop1.XTickLabel = {'0'; '250'; '500'; '750'; '1000'};
            app.tcpop1.YTick = [-1 -0.5 0 0.5 1];
            app.tcpop1.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.tcpop1.ZTick = 0;
            app.tcpop2 = uiaxes(app.poppanel);
            title(app.tcpop2, '')
            xlabel(app.tcpop2, '')
            ylabel(app.tcpop2, '')
            app.tcpop2.XLim = [0 500];
            app.tcpop2.YLim = [-0.8 0.8];
            app.tcpop2.XTick = [0 250 500 750 1000];
            app.tcpop2.XTickLabel = {'0'; '250'; '500'; '750'; '1000'};
            app.tcpop2.YTick = [-1 -0.5 0 0.5 1];
            app.tcpop2.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.tcpop2.ZTick = 0;
            app.tcpop3 = uiaxes(app.poppanel);
            title(app.tcpop3, '')
            xlabel(app.tcpop3, '')
            ylabel(app.tcpop3, '')
            app.tcpop3.XLim = [0 500];
            app.tcpop3.YLim = [-0.8 0.8];
            app.tcpop3.XTick = [0 250 500 750 1000];
            app.tcpop3.XTickLabel = {'0'; '250'; '500'; '750'; '1000'};
            app.tcpop3.YTick = [-1 -0.5 0 0.5 1];
            app.tcpop3.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.tcpop3.ZTick = 0;
            app.tcpop4 = uiaxes(app.poppanel);
            title(app.tcpop4, '')
            xlabel(app.tcpop4, '')
            ylabel(app.tcpop4, '')
            app.tcpop4.XLim = [0 500];
            app.tcpop4.YLim = [-0.8 0.8];
            app.tcpop4.XTick = [0 250 500 750 1000];
            app.tcpop4.XTickLabel = {'0'; '250'; '500'; '750'; '1000'};
            app.tcpop4.YTick = [-1 -0.5 0 0.5 1];
            app.tcpop4.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.tcpop4.ZTick = 0;
            app.nlpop1 = uiaxes(app.poppanel);
            title(app.nlpop1, '')
            xlabel(app.nlpop1, '')
            ylabel(app.nlpop1, '')
            app.nlpop1.XLim = [-3.1 3.1];
            app.nlpop1.XTick = [-3 -1.5 0 1.5 3];
            app.nlpop2 = uiaxes(app.poppanel);
            title(app.nlpop2, '')
            xlabel(app.nlpop2, '')
            ylabel(app.nlpop2, '')
            app.nlpop2.XLim = [-3.1 3.1];
            app.nlpop2.XTick = [-3 -1.5 0 1.5 3];
            app.nlpop3 = uiaxes(app.poppanel);
            title(app.nlpop3, '')
            xlabel(app.nlpop3, '')
            ylabel(app.nlpop3, '')
            app.nlpop3.XLim = [-3.1 3.1];
            app.nlpop3.XTick = [-3 -1.5 0 1.5 3];
            app.nlpop4 = uiaxes(app.poppanel);
            title(app.nlpop4, '')
            xlabel(app.nlpop4, '')
            ylabel(app.nlpop4, '')
            app.nlpop4.XLim = [-3.1 3.1];
            app.nlpop4.XTick = [-3 -1.5 0 1.5 3];
            app.pcagrid = uigridlayout(app.pcapoppanel);
            app.pca1 = uiaxes(app.pcagrid);
            title(app.pca1, 'Title')
            xlabel(app.pca1, 'X')
            ylabel(app.pca1, 'Y')
            app.pca2 = uiaxes(app.pcagrid);
            title(app.pca2, 'Title')
            xlabel(app.pca2, 'X')
            ylabel(app.pca2, 'Y')
            app.pca3 = uiaxes(app.pcagrid);
            title(app.pca3, 'Title')
            xlabel(app.pca3, 'X')
            ylabel(app.pca3, 'Y')
            app.singlecellpanel = uigridlayout(app.datapanel2);
            app.acg = uiaxes(app.singlecellpanel);
            title(app.acg, 'auto-correlogram')
            xlabel(app.acg, 'time (ms)')
            ylabel(app.acg, '')
            app.acg.XLim = [0 50];
            app.acg.XTick = [0 25 50 75 100 150];
            app.tempcomp = uiaxes(app.singlecellpanel);
            title(app.tempcomp, 'temporal component')
            xlabel(app.tempcomp, 'delay (ms)')
            ylabel(app.tempcomp, '')
            app.tempcomp.XLim = [0 500];
            app.tempcomp.YLim = [-0.8 0.8];
            app.tempcomp.XTick = [0 250 500 750 1000];
            app.tempcomp.XTickLabel = {'0'; '250'; '500'; '750'; '1000'};
            app.tempcomp.YTick = [-1 -0.5 0 0.5 1];
            app.tempcomp.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.tempcomp.ZTick = 0;
            app.RFall = uiaxes(app.singlecellpanel);
            title(app.RFall, 'center')
            xlabel(app.RFall, '')
            ylabel(app.RFall, '')
            app.RFall.XLim = [0 800];
            app.RFall.YLim = [0 600];
            app.RFall.XTick = [];
            app.RFall.YTick = [];
            app.RFall.ZTick = [];
            app.spatialcomp = uiaxes(app.singlecellpanel);
            title(app.spatialcomp, 'quality')
            xlabel(app.spatialcomp, '')
            ylabel(app.spatialcomp, '')
            app.spatialcomp.XTick = [];
            app.spatialcomp.YTick = [];
            app.sta = uiaxes(app.singlecellpanel);
            title(app.sta, '')
            xlabel(app.sta, '')
            ylabel(app.sta, '')
            app.sta.XTick = [];
            app.sta.YTick = [];
            app.dspanel = uigridlayout(app.singlecellpanel);
            app.ds1 = uiaxes(app.dspanel);
            title(app.ds1, '')
            xlabel(app.ds1, '')
            ylabel(app.ds1, '')
            app.ds1.XTick = [];
            app.ds1.YTick = [];
            app.ds1.ZTick = [];
            app.ds2 = uiaxes(app.dspanel);
            title(app.ds2, '')
            xlabel(app.ds2, '')
            ylabel(app.ds2, '')
            app.ds2.XTick = [];
            app.ds2.YTick = [];
            app.ds2.ZTick = [];
            app.ds3 = uiaxes(app.dspanel);
            title(app.ds3, '')
            xlabel(app.ds3, '')
            ylabel(app.ds3, '')
            app.ds3.XTick = [];
            app.ds3.YTick = [];
            app.ds3.ZTick = [];
            app.ds4 = uiaxes(app.dspanel);
            title(app.ds4, '')
            xlabel(app.ds4, '')
            ylabel(app.ds4, '')
            app.ds4.XTick = [];
            app.ds4.YTick = [];
            app.ds4.ZTick = [];
            app.ds5 = uiaxes(app.dspanel);
            title(app.ds5, '')
            xlabel(app.ds5, '')
            ylabel(app.ds5, '')
            app.ds5.XTick = [];
            app.ds5.YTick = [];
            app.ds5.ZTick = [];
            app.ds6 = uiaxes(app.dspanel);
            title(app.ds6, '')
            xlabel(app.ds6, '')
            ylabel(app.ds6, '')
            app.ds6.XTick = [];
            app.ds6.XTickLabel = {};
            app.ds6.YTick = [];
            app.ds6.YTickLabel = {};
            app.ds6.ZTick = [];
            app.celltitle = uilabel(app.datapanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifyRGCs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end